classdef schemaViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TypeEditField             matlab.ui.control.EditField
        TypeEditFieldLabel        matlab.ui.control.Label
        Hyperlink                 matlab.ui.control.Hyperlink
        DescriptionTextArea       matlab.ui.control.TextArea
        DescriptionTextAreaLabel  matlab.ui.control.Label
        DefinitionEditField       matlab.ui.control.EditField
        DefinitionEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Dependent)
        Schema % Description
        Visible
    end
    
    methods
        
        function set.Schema(app, S)
            app.Name = sprintf('Schema Information: %s', S.type);
            app.TypeEditField.Value = S.name;
            app.DefinitionEditField.Value = S.definition;
            app.Description.Value = S.description;
            app.Hyperlink.URL = S.interlexIdentifier;
        end

        function set.Visible(app, newValue)
            app.UIFigure.Visible = newValue;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DefinitionEditFieldLabel = uilabel(app.UIFigure);
            app.DefinitionEditFieldLabel.Text = 'Definition';
            app.DefinitionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Text = 'View on Interlex  ';
            app.TypeEditFieldLabel = uilabel(app.UIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = schemaViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end